classdef FlightCockpit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightInstrumentsFlightDataPlaybackUIFigure  matlab.ui.Figure
        StopButton                     matlab.ui.control.Button
        StartVisualizationButton       matlab.ui.control.Button
        FlightCockpitDataDisplayLabel  matlab.ui.control.Label
        ClimbIndicator                 Aero.ui.control.ClimbIndicator
        HeadingIndicator               Aero.ui.control.HeadingIndicator
        TurnCoordinator                Aero.ui.control.TurnCoordinator
        Altimeter                      Aero.ui.control.Altimeter
        ArtificialHorizon              Aero.ui.control.ArtificialHorizon
        AirspeedIndicator              Aero.ui.control.AirspeedIndicator
        UIAxes_rates                   matlab.ui.control.UIAxes
        UIAxes_euler_angles            matlab.ui.control.UIAxes
    end

   
    properties (Access = public) 
        timer   % variable holds timer for visualization
        currentTime % current time of visualization timer
        simdata % Saved flight data [time X Y Z phi theta psi]
        animObj % Aero.Animation object
        output  % The output states of the GTM Model
        t       % Time data
        tplot   % Interpolated time data for plotting
        Xeom    % EOM state data
        y       % State data from simulation
        dist_lat % Latitude distance
        dist_lon % Longitude distance
        alt      % Altitude data
        Vehicletop      % Top view of the vehicle
        Vehiclebot      % Bottom view of the vehicle
        simulationTime
    end
    
    % my own methods
    methods (Access = private)
        function initPlots(app)
            disp('initializing plots')
        end

        function updatePlots(app)
            app.currentTime; % current time
            % get index of current time
        end

        function updateInstruments(app)
            k = find(app.simdata(:,1) <= app.timer, 1, 'last');  % Use 'last' to get the last valid index
            disp("Updating instruments")
        end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% About
            %
            % Author:     Omar Mourad
            % Email:      <mailto: st181901@stud.uni-stuttgart.de>
            % Created:    28.03.2024 
            
            %% ------------------ Initializing the Upset Condition ------------------
            selectedSimulationType = 'Oscillatory Spin';  % Change this to 'Steep Spiral' as needed
            runSim = false; % Run simulation before visualization?

            %% Initialize the simulation
            switch selectedSimulationType
                case 'Oscillatory Spin'
                    if runSim
                        run('OscillatorySpin.m'); % Run the Oscillatory Spin simulation
                    end
                    data = load('OscillatorySpinData.mat'); % Load corresponding data
                case 'Steep Spiral'
                    if runSim
                        run('SteepSpiral.m'); % Run the Steep Spiral simulation
                    end
                    data = load('SteepSpiralData.mat'); % Load corresponding data
                otherwise
                    error('Unknown simulation type selected.');
            end

            % Assign data to app properties for further use
            app.t = data.t;                % Time data
            app.simulationTime = data.t;
            app.tplot = data.tplot;        % Interpolated time data for plotting
            app.y = data.y;                % State data from simulation
            app.Xeom = data.Xeom;          % EOM state data
            app.dist_lat = data.dist_lat;  % Latitude distance
            app.dist_lon = data.dist_lon;  % Longitude distance
            app.alt = data.alt;            % Altitude data
            
            %% Define simple airplane shape
            scale = 10;
            x1 = scale * ([0.0, -0.5, -2.0, -3.0, -4.0, -3.25, -5.5, -6.0, -6.0] + 3.0);
            y1 = scale * [0.0, 0.5, 0.5, 4.25, 4.5, 0.5, 0.5, 1.5, 0.0];
            app.Vehicletop = [[x1, fliplr(x1)]; [y1, fliplr(-y1)]; -0.01 * ones(1, 2 * length(x1))];
            app.Vehiclebot = [[x1, fliplr(x1)]; [y1, fliplr(-y1)]; 0.01 * ones(1, 2 * length(x1))];
            
            %plotStaticTrajectory(app);
            %plotStaticTrajectoryAtTime(app, 0);


            %% Loading Upset Flight Data
            app.output = app.y;
            % Load simulated GTM Upset flight data
            f2m = 0.3048; % feet to m
            Data = [app.t app.dist_lat*f2m app.dist_lon*f2m app.alt*f2m app.output(:,16) app.output(:,17) app.output(:,18)];
            % Data = [time (secs), X-Distance (m), Y-Distance (m), Altitude (m), Phi (rad), Theta (rad), Psi (rad)]
       
            yaw = Data(:,7);
            yaw(yaw<0) = yaw(yaw<0)+2*pi; % unwrap yaw angles
            Data(:,7) = yaw;
            app.simdata = Data;
            
            app.initPlots()
        end

        % Callback function
        function Time000secSliderValueChanging(app, event)

            
        end

        % Close request function: 
        % FlightInstrumentsFlightDataPlaybackUIFigure
        function FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest(app, event)
            % Close animation figure with app
            delete(app.animObj);
            delete(app); 
           
        end

        % Button pushed function: StartVisualizationButton
        function StartVisualizationButtonPushed(app, event)
            % Define the total simulation time
                totalTime = app.simulationTime;  % Assuming app.simulationTime holds the total time in seconds
            
                % Initialize the current time
                app.currentTime = 0;  % Initialize to 0 at the start of the simulation
            
                % Create a timer
                if isempty(app.timer) || ~strcmp(app.timer.Running, 'on')
                    app.timer = timer;
                    app.timer.StartDelay = 0; % Start immediately
                    app.timer.Period = 0.1; % Update every 0.1 seconds (adjust as needed)
                    app.timer.ExecutionMode = 'fixedRate'; % Fixed rate execution
                    app.timer.BusyMode = 'queue'; % Queue additional executions if the timer is busy
                    app.timer.TimerFcn = @(~, ~) updateTimer(app, totalTime); % Update function
            
                    % Start the timer
                    start(app.timer);
                end
            end
            
            % Timer update function
            function updateTimer(app, totalTime)
                % Update the current time
                app.currentTime = app.currentTime + app.timer.Period;  % Increment the current time
            
                % Perform visualization updates here based on app.currentTime
                % disp(['Current simulation time: ', num2str(app.currentTime), ' seconds']);

                app.updatePlots()
                app.updateInstruments()
            
                % Stop the timer if the total time is exceeded
                if app.currentTime >= totalTime
                    stop(app.timer);
                    disp('Simulation complete.');
                end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.timer)
                stop(app.timer);
                disp('timer stopped')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightInstrumentsFlightDataPlaybackUIFigure = uifigure('Visible', 'off');
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Name = 'Flight Instruments - Flight Data Playback';
            app.FlightInstrumentsFlightDataPlaybackUIFigure.CloseRequestFcn = createCallbackFcn(app, @FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest, true);
            app.UIAxes_euler_angles = uiaxes(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            title(app.UIAxes_euler_angles, 'Angular rates')
            xlabel(app.UIAxes_euler_angles, 't [s]')
            ylabel(app.UIAxes_euler_angles, 'Rates [deg/s]')
            zlabel(app.UIAxes_euler_angles, 'Z')
            app.UIAxes_rates = uiaxes(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            title(app.UIAxes_rates, 'Euler Angles')
            xlabel(app.UIAxes_rates, 't [s]')
            ylabel(app.UIAxes_rates, 'Angles [deg]')
            zlabel(app.UIAxes_rates, 'Z')
            app.AirspeedIndicator = uiaeroairspeed(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.AirspeedIndicator.Limits = [25 250];
            app.ArtificialHorizon = uiaerohorizon(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Altimeter = uiaeroaltimeter(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.TurnCoordinator = uiaeroturn(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.HeadingIndicator = uiaeroheading(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator = uiaeroclimb(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator.MaximumRate = 8000;
            app.FlightCockpitDataDisplayLabel = uilabel(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.FlightCockpitDataDisplayLabel.Text = 'Flight Cockpit Data Display';
            app.StartVisualizationButton = uibutton(app.FlightInstrumentsFlightDataPlaybackUIFigure, 'push');
            app.StartVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartVisualizationButtonPushed, true);
            app.StartVisualizationButton.Text = 'StartVisualization';
            app.StopButton = uibutton(app.FlightInstrumentsFlightDataPlaybackUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightCockpit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightInstrumentsFlightDataPlaybackUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightInstrumentsFlightDataPlaybackUIFigure)
        end
    end
end